classdef ImageGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RetourButton                    matlab.ui.control.Button
        DisplayReading                  matlab.ui.control.EditField
        LectureLabel                    matlab.ui.control.Label
        DisplayResult                   matlab.ui.control.EditField
        Lapluslonguesuitede0estobtenueparlalesLecturesLabel  matlab.ui.control.Label
        ConfirmerButton                 matlab.ui.control.Button
        SelectReadDropDown              matlab.ui.control.DropDown
        ChoisissezletypedelectureLabel  matlab.ui.control.Label
        VeuillezchargervotreimageiciLabel  matlab.ui.control.Label
        ImageSelect                     matlab.ui.control.Image
        CodagedesImagesLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end

    
    methods (Access = private)
        
function [readVal, somme0] = VerticalReading(app)
    global ourImage;
    [L, C] = size(ourImage);
    V = zeros(1, L * C);  % Initialize array with correct size
    f = 1;
    for j = 1:C
        for i = 1:L
            V(f) = double(ourImage(i, j));
            f = f + 1;  % Increment index within the nested loop
        end
    end
    readVal = V;
    
    % Calculate somme0: the count of the largest consecutive zeros
    linearIndices = find(V == 0);
    somme0 = 1;
    b = diff(linearIndices) == 1;
    i = 1;
    while i <= length(b)
        if b(i) == 1
            currentCount = 1;
            while i <= length(b) && b(i) == 1
                currentCount = currentCount + 1;
                i = i + 1;
            end
            somme0 = max(somme0, currentCount);
        else
            i = i + 1;
        end
    end
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

function [readVal, somme0] = HorizontalReading(app)
    global ourImage;
    [L, C] = size(ourImage);
    H = zeros(1, L * C);  % Initialize array with correct size
    k = 1;
    for i = 1:L
        for j = 1:C
            H(k) = double(ourImage(i, j));
            k = k + 1;  % Increment index within the nested loop
        end
    end
    readVal = H;
    
    % Calculate somme0: the count of the largest consecutive zeros
    linearIndices = find(H == 0);
    somme0 = 1;
    b = diff(linearIndices) == 1;
    i = 1;
    while i <= length(b)
        if b(i) == 1
            currentCount = 1;
            while i <= length(b) && b(i) == 1
                currentCount = currentCount + 1;
                i = i + 1;
            end
            somme0 = max(somme0, currentCount);
        else
            i = i + 1;
        end
    end
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

function [readVal, somme0] = ZigzagReading(app)
    global ourImage;
    [L, C] = size(ourImage);
    zigzag = zeros(1, L * C);  % Initialize array with correct size
    z = 1;
    
    % Filling the zigzag array
    for i = 1:L
        if mod(i, 2) ~= 0
            for j = 1:i
                zigzag(z) = double(ourImage(j, i + 1 - j));
                z = z + 1;
            end
        else
            for j = i:-1:1
                zigzag(z) = double(ourImage(j, i + 1 - j));
                z = z + 1;
            end
        end
    end

    if mod(L, 2) == 0
        zig = 1;
    else
        zig = 0;
    end

    for i = L+1:2*L-1
        if mod(zig, 2) ~= 0
            for j = L:-1:i+1-L
                zigzag(z) = double(ourImage(j, i + 1 - j));
                z = z + 1;
            end
        else
            for j = L:-1:i+1-L
                zigzag(z) = double(ourImage(i + 1 - j, j));
                z = z + 1;
            end
        end
        zig = zig + 1;
    end
    
    readVal = zigzag;  % Correctly assign the zigzag array to readVal

    % Calculate somme0: the count of the largest consecutive zeros
    linearIndices = find(zigzag == 0);
    somme0 = 0;  % Initialize somme0
    if ~isempty(linearIndices)
        b = diff(linearIndices) == 1;
        currentCount = 1;
        maxCount = 1;
        for i = 1:length(b)
            if b(i)
                currentCount = currentCount + 1;
                maxCount = max(maxCount, currentCount);
            else
                currentCount = 1;
            end
        end
        somme0 = maxCount;
    end
end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UploadButtonPushed(app, event)
            [filename, path]=uigetfile;
            temp=fileread(filename);
            app.DisplayReading.Value=temp;
            figure();
        end

        % Image clicked function: ImageSelect
        function ImageSelectClicked(app, event)

    % Allow user to select an image file
    [filename, filepath] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select Image File');
    
    % Check if the user selected a file
    if isequal(filename, 0)
        % User canceled the operation
        return;
    end
    
    % Read the selected image file
    image = imread(fullfile(filepath, filename));
    
    % Get the dimensions of the image
    [rows, cols, channels] = size(image);
    
    % Check if the image is not square
    if rows ~= cols
        % Determine the size to pad to make it square
        maxSize = max(rows, cols);
        
        % Initialize a new square image with zeros
        if channels == 1
            squareImage = zeros(maxSize, maxSize, 'like', image);
        else
            squareImage = zeros(maxSize, maxSize, channels, 'like', image);
        end
        
        % Copy the original image into the square image
        squareImage(1:rows, 1:cols, :) = image;
        
        % Update the image to the new square image
        image = squareImage;
    end
    
    % Store the image in the global variable for further processing
    global ourImage;
    ourImage = image;
    
    % Convert the image to grayscale if it's a color image
    if size(ourImage, 3) == 3
        ourImage = rgb2gray(ourImage);
    end
    
    % Create a new figure window to display the image
    figure('Name', 'Notre image après traitement', 'NumberTitle', 'off', 'Position', [100, 100, 600, 400]);
    imshow(ourImage);
    title('Image Traitée');

        end

        % Button pushed function: ConfirmerButton
        function ConfirmerButtonPushed(app, event)
ReadingType = app.SelectReadDropDown.Value;
            
            % Choose the vector to display based on reading type
            [readValH,sommeH0] = HorizontalReading(app);
            [readValV,sommeV0] = VerticalReading(app);
            [readValZ,sommeZ0] = ZigzagReading(app);
            
            switch ReadingType
                case 'Lecture Horizontale'
                    readVal = readValH;
                case 'Lecture Verticale'
                    readVal = readValV;
                case 'Lecture en Zigzag'
                    readVal = readValZ;
            end
            app.DisplayReading.Value = evalc('readVal');
            
            % Display the conclusion in the appropriate edit field
            choice='';
            if sommeH0>=sommeV0 && sommeH0>=sommeZ0
                choice = 'Lecture Horizontale';
            end
            if sommeV0>=sommeH0 && sommeV0>=sommeZ0
                choice = [choice, ' Lecture Verticale'];
            end
            if sommeZ0>=sommeH0 && sommeZ0>=sommeV0
                choice = [choice,  ' Lecture en Zigzag'];
            end
            
            app.DisplayResult.Value = choice;
        end

        % Button pushed function: RetourButton
        function RetourButtonPushed(app, event)
            Home;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logoENSAF.PNG';
            app.CodagedesImagesLabel = uilabel(app.UIFigure);
            app.CodagedesImagesLabel.Text = 'Codage des Images';
            app.ImageSelect = uiimage(app.UIFigure);
            app.ImageSelect.ImageClickedFcn = createCallbackFcn(app, @ImageSelectClicked, true);
            app.VeuillezchargervotreimageiciLabel = uilabel(app.UIFigure);
            app.VeuillezchargervotreimageiciLabel.Text = {'Veuillez charger votre image ici:'; ''};
            app.ChoisissezletypedelectureLabel = uilabel(app.UIFigure);
            app.ChoisissezletypedelectureLabel.Text = 'Choisissez le type de lecture:';
            app.SelectReadDropDown = uidropdown(app.UIFigure);
            app.SelectReadDropDown.Items = {'Lecture Horizontale', 'Lecture Verticale', 'Lecture en Zigzag'};
            app.SelectReadDropDown.Value = 'Lecture Horizontale';
            app.ConfirmerButton = uibutton(app.UIFigure, 'push');
            app.ConfirmerButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmerButtonPushed, true);
            app.ConfirmerButton.Text = 'Confirmer';
            app.Lapluslonguesuitede0estobtenueparlalesLecturesLabel = uilabel(app.UIFigure);
            app.Lapluslonguesuitede0estobtenueparlalesLecturesLabel.Text = 'La plus longue suite de 0 est obtenue par la (les) Lecture(s):';
            app.DisplayResult = uieditfield(app.UIFigure, 'text');
            app.LectureLabel = uilabel(app.UIFigure);
            app.LectureLabel.Text = {'Vecteur résultant '; 'de la lecture :'};
            app.DisplayReading = uieditfield(app.UIFigure, 'text');
            app.RetourButton = uibutton(app.UIFigure, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @RetourButtonPushed, true);
            app.RetourButton.Text = 'Retour';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end