classdef TextGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RetourButton                 matlab.ui.control.Button
        TextFieldCompar2             matlab.ui.control.EditField
        Comparaison2Button           matlab.ui.control.Button
        TextFieldCompar1             matlab.ui.control.EditField
        Comparaison1Button           matlab.ui.control.Button
        CmpressionRatioEditField     matlab.ui.control.NumericEditField
        LetauxdecompressionestLabel  matlab.ui.control.Label
        ConfirmerButton              matlab.ui.control.Button
        SlectionnezletypeducodageDropDown  matlab.ui.control.DropDown
        SlectionnezletypeducodageDropDownLabel  matlab.ui.control.Label
        VeuillezentezvotretexticiEditField  matlab.ui.control.EditField
        VeuillezentezvotretexticiEditFieldLabel  matlab.ui.control.Label
        CodagedutextLabel            matlab.ui.control.Label
        Image                        matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function result = shannonFanoEncoding(app, inputText)
            % Convert inputText to char array
            text = char(inputText);
            % Unique symbols in the text
            symboles = unique(text);
            Pr = zeros(1, length(symboles));
            % Calculate probability of each symbol
            for i = 1:length(symboles)
                k = strfind(text, symboles(i));
                n = length(k);
                Pr(i) = n / length(text);
            end
            
            % Placeholder for Shannon-Fano encoding function (to be implemented)
            code = ShannonFanoCode(symboles, Pr); % Ensure this function is defined
            m_code = EntropyEncoder(text, code);  % Ensure this function is defined

            % Calculate the compression ratio
            R_Shanon_Fano = length(m_code) / (length(text) * 8);
            result = double(R_Shanon_Fano);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function result = huffmanEncoding(app, inputText)
            % Convert inputText to char array
            text = char(inputText);
            % Unique symbols in the text
            symboles = unique(text);
            Pr = zeros(1, length(symboles));
            % Calculate probability of each symbol
            for i = 1:length(symboles)
                k = strfind(text, symboles(i));
                n = length(k);
                Pr(i) = n / length(text);
            end
            symboles=double(symboles);
            % Placeholder for Huffman encoding function 
            code = huffmandict(symboles, Pr); 
            m_code = huffmanenco(text, code);  

            % Calculate the compression ratio
            R_Huffman = length(m_code) / (length(text) * 8);
            result = double(R_Huffman);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function result = lzEncoding(app, inputText)
            text = char(inputText);
        
            % Perform LZ78 encoding
            [encodedText, dict] = LZ78Encoder(text, 0);
        
            % Calculate the length of the encoded text in bits
            encodedLength = length(encodedText) * 8;  % Assuming each character is encoded in 8 bits
        
            % Calculate the compression ratio
            originalLength = length(text) * 8;  % Assuming each character is 8 bits in the original text
            R_LZ = encodedLength / originalLength;
        
            % Convert compression ratio to double
            result = double(R_LZ);
            
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function result = lzwEncoding(app, inputText)
            text = char(inputText);
        
        % Perform LZW encoding
        [encodedText, dict] = LZWEncoder(text, unique(text), 0);
        
        % Calculate the length of the encoded text in bits
        encodedLength = length(encodedText) * 8;  % Assuming each character is encoded in 8 bits
        
        % Calculate the compression ratio
        originalLength = length(text) * 8;  % Assuming each character is 8 bits in the original text
        R_LZW = encodedLength / originalLength;
        
        % Convert compression ratio to double
            result = double(R_LZW);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConfirmerButton
        function ConfirmerButtonPushed(app, event)
            value = app.VeuillezentezvotretexticiEditField.Value;
            inputText = char(value);
            encodingType = app.SlectionnezletypeducodageDropDown.Value;
            
            % Choose the function to execute based on encoding type
            switch encodingType
                case 'Huffman'
                    result = huffmanEncoding(app,inputText);
                case 'Shanon Fano'
                    result = shannonFanoEncoding(app,inputText);
                case 'LZ'
                    result = lzEncoding(app,inputText);
                case 'LZW'
                    result = lzwEncoding(app,inputText);
            end
            
            % Display the result in the appropriate edit field
            app.CmpressionRatioEditField.Value = result;
       
        end

        % Button pushed function: Comparaison1Button
        function Comparaison1ButtonPushed(app, event)
            value = app.VeuillezentezvotretexticiEditField.Value;
            input = char(value);
            R_Shanon_Fano=shannonFanoEncoding(app, input);
            R_Huffman=huffmanEncoding(app, input);
            if (R_Huffman == R_Shanon_Fano)
                app.TextFieldCompar1.Value = 'Les deux algorithmes sont équivalents';
            elseif (R_Huffman > R_Shanon_Fano)
                app.TextFieldCompar1.Value = 'Huffman est meilleur';
            end
        end

        % Button pushed function: Comparaison2Button
        function Comparaison2ButtonPushed(app, event)
            value = app.VeuillezentezvotretexticiEditField.Value;
            input = char(value);
            R_LZ=lzEncoding(app, input);
            R_LZW=lzwEncoding(app, input);
            if (R_LZ == R_LZW)
                app.TextFieldCompar2.Value = 'Les deux algorithmes sont équivalents';
            elseif (R_LZW > R_LZ)
                app.TextFieldCompar2.Value = 'LZW est meilleur';
            end
        end

        % Button pushed function: RetourButton
        function RetourButtonPushed(app, event)
            Home;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logoENSAF.PNG';
            app.CodagedutextLabel = uilabel(app.UIFigure);
            app.CodagedutextLabel.Text = {'Codage du text'; ''};
            app.VeuillezentezvotretexticiEditFieldLabel = uilabel(app.UIFigure);
            app.VeuillezentezvotretexticiEditFieldLabel.Text = 'Veuillez entez votre text ici:';
            app.VeuillezentezvotretexticiEditField = uieditfield(app.UIFigure, 'text');
            app.SlectionnezletypeducodageDropDownLabel = uilabel(app.UIFigure);
            app.SlectionnezletypeducodageDropDownLabel.Text = 'Sélectionnez le type du codage:';
            app.SlectionnezletypeducodageDropDown = uidropdown(app.UIFigure);
            app.SlectionnezletypeducodageDropDown.Items = {'Huffman', 'Shanon Fano', 'LZ', 'LZW'};
            app.SlectionnezletypeducodageDropDown.Value = 'Huffman';
            app.ConfirmerButton = uibutton(app.UIFigure, 'push');
            app.ConfirmerButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmerButtonPushed, true);
            app.ConfirmerButton.Text = 'Confirmer';
            app.LetauxdecompressionestLabel = uilabel(app.UIFigure);
            app.LetauxdecompressionestLabel.Text = 'Le taux de compression est:';
            app.CmpressionRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.Comparaison1Button = uibutton(app.UIFigure, 'push');
            app.Comparaison1Button.ButtonPushedFcn = createCallbackFcn(app, @Comparaison1ButtonPushed, true);
            app.Comparaison1Button.Text = 'Comparaison entre Huffman et Shannon-Fano';
            app.TextFieldCompar1 = uieditfield(app.UIFigure, 'text');
            app.Comparaison2Button = uibutton(app.UIFigure, 'push');
            app.Comparaison2Button.ButtonPushedFcn = createCallbackFcn(app, @Comparaison2ButtonPushed, true);
            app.Comparaison2Button.Text = 'Comparaison entre LZ et LZW';
            app.TextFieldCompar2 = uieditfield(app.UIFigure, 'text');
            app.RetourButton = uibutton(app.UIFigure, 'push');
            app.RetourButton.ButtonPushedFcn = createCallbackFcn(app, @RetourButtonPushed, true);
            app.RetourButton.Text = 'Retour';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TextGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end